classdef Updated_project_initial_PID_included_Attempt_8 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        StandardDeviationLabel          matlab.ui.control.Label
        AverageTempLabel                matlab.ui.control.Label
        SetTemperaturetoEditField       matlab.ui.control.NumericEditField
        SetTemperaturetoEditFieldLabel  matlab.ui.control.Label
        ShutdownButton                  matlab.ui.control.Button
        RunButton                       matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        comport % Description 
        desired_temp
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            app.comport = serialport('COM10',115200);
            % Send 'U' to trigger sampling
            writeline(app.comport, "U");
            app.desired_temp=25;
            count = 0;


            % PID Controller Parameters
            ProportionalGain = 10;    % Adjust this to control responsiveness
            IntegralGain = 1;       % Helps eliminate steady-state error
            DerivativeGain = 0.1;       % Helps dampen oscillations

            % Control Variables

            integral_error = 0;       % Cumulative error for integral term
            prev_error = 0;       % For derivative calculation

            % Maximum and Minimum Control Outputs
            max_output = 1000;         % Maximum PWM value
            min_output = 0;           % Minimum PWM value


            % Plot data try plotting data instead of Voltage
            t = 0:399;
            time=t/115200;
            delta_t=time(400);

            time_data = [];
            temp_data = [];
            start_time = tic;

            while (1) % Synchronization loop
                % Read data from serial
                data = read(app.comport, 400, "uint8");
                V_ref = 3.3;
                vinit = double(data);
                voltage = (vinit ./ 255) .* V_ref;
                R_TEC = voltage ./ (V_ref - voltage) .* 10000;
                %current_temp = 69.807 .* exp(-0.0001 .* R_TEC);
                current_temp = 67.5* exp(-0.0001 .* R_TEC)-2;

                % Compute elapsed time
                elapsed_time = toc(start_time); % Get elapsed time since start
                delta_t = 400 / 115200; % Time step per batch
                new_time = elapsed_time + (0:delta_t:(delta_t * (length(current_temp) - 1)));
                % Appending new time series data to existing data

                % Calculate Average Temperature
                avg_temp = mean(current_temp);
                stdev = std(current_temp);

                time_avg=mean(new_time);

                % Append new data to the buffers
                %time_data = [time_data, new_time];
                %temp_data = [temp_data, current_temp];
                
                %Average values calculated for plotting
                time_data = [time_data, time_avg];
                temp_data = [temp_data, avg_temp];

                % Limit data buffer size for a sliding window effect (e.g., last 5000 samples)
                if length(time_data) > 5000
                    time_data = time_data(end-5000:end);
                    temp_data = temp_data(end-5000:end);
                end
                            
                % Update App Designer UI Element
                app.AverageTempLabel.Text = sprintf('Current Temperature: %.2f C',avg_temp); % Update Numeric Edit Field
                app.StandardDeviationLabel.Text = sprintf('Current Standard Deviation %.2f C',stdev);

                if(abs(avg_temp-app.desired_temp)<3)
                    ProportionalGain = 0.001;
                    IntegralGain=0;
                    DerivativeGain = 0;

                else
                    ProportionalGain = 4;    % Adjust this to control responsiveness
                    IntegralGain = 1;       % Helps eliminate steady-state error
                    DerivativeGain = 0.01;       % Helps dampen oscillations
                end

                % PID calculations

                error = app.desired_temp - avg_temp;
                integral_error = integral_error + error * delta_t; 
                derivative_error = (error - prev_error) / delta_t;
                
                control_output = ProportionalGain * error + IntegralGain * integral_error + DerivativeGain * derivative_error;
                prev_error = error;
                
                if(app.desired_temp < 10 && app.desired_temp < avg_temp && avg_temp-app.desired_temp<15 && abs(avg_temp-app.desired_temp)>1.5)
                    control_output = control_output-100;
                end

                control_sig_mag = uint8(min(abs(control_output), 255));
                sign = uint8(control_output >= 0);
                

                if(control_sig_mag<2)
                    if(count==0 || abs(avg_temp-app.desired_temp)>5)
                        if sign == 1
                            if(abs(avg_temp-app.desired_temp)<0.03)
                                control_sig_mag = 2;
                                count=1;
                            else
                                control_sig_mag = 3;
                                count=0;
                            end
                        else
                            if(abs(avg_temp-app.desired_temp)<0.03)
                                control_sig_mag = 2;
                                count=1;
                            else
                               control_sig_mag = 3;
                               count=0;
                            end
                        end
                    else
                        control_sig_mag=2;
                    end
                else
                    count=0;
                end

                %if(control_sig_mag<2)
                %   control_sig_mag=2;
                %end

                write(app.comport, [sign; control_sig_mag], "uint8");

                % Dynamic plotting
                ax = app.UIAxes;
                %plot(ax,vinit,'b', 'LineWidth', 1.5);
                plot(ax, time_data, temp_data, 'b', 'LineWidth', 1.5);
                hold(ax, 'on');

                % Set temperature line
                if app.SetTemperaturetoEditField.Value > 0
                    setplot = app.SetTemperaturetoEditField.Value * ones(size(time_data));
                    plot(ax, time_data, setplot, 'r', 'LineWidth', 1.5);
                end

                % Adjust axes limits
                ax.XLim = [max(0, time_data(end) - 10), time_data(end)]; % Show last 10s
                ax.YLim = [0, 50];

                xlabel(ax, 'Time (s)');
                ylabel(ax, 'Temperature (°C)');
                title(ax, 'Real-Time Temperature Data');
                grid(ax, 'on');
                drawnow;
                hold(ax, 'off');

                flush(app.comport);
            end
        end

        % Button pushed function: ShutdownButton
        function ShutdownButtonPushed(app, event)
            clear("app.comport")
            close
            delete (app)
        end

        % Value changed function: SetTemperaturetoEditField
        function SetTemperaturetoEditFieldValueChanged(app, event)
            app.desired_temp = app.SetTemperaturetoEditField.Value;
        end

        % Callback function
        function KpButtonPushed(app, event)

            app.PGainValueLabel.Text = num2str(app.ProportionalGain);
        end

        % Callback function
        function KpButton_2Pushed(app, event)

            app.PGainValueLabel.Text = num2str(app.ProportionalGain);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.ShutdownButton = uibutton(app.UIFigure, 'push');
            app.ShutdownButton.ButtonPushedFcn = createCallbackFcn(app, @ShutdownButtonPushed, true);
            app.ShutdownButton.Text = 'Shutdown';
            app.SetTemperaturetoEditFieldLabel = uilabel(app.UIFigure);
            app.SetTemperaturetoEditFieldLabel.Text = 'Set Temperature to';
            app.SetTemperaturetoEditField = uieditfield(app.UIFigure, 'numeric');
            app.SetTemperaturetoEditField.Limits = [5 45];
            app.SetTemperaturetoEditField.ValueChangedFcn = createCallbackFcn(app, @SetTemperaturetoEditFieldValueChanged, true);
            app.SetTemperaturetoEditField.Value = 25;
            app.AverageTempLabel = uilabel(app.UIFigure);
            app.AverageTempLabel.Text = 'Average Temp: ';
            app.StandardDeviationLabel = uilabel(app.UIFigure);
            app.StandardDeviationLabel.Text = 'Standard Deviation: ';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Updated_project_initial_PID_included_Attempt_8

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end